classdef ASMDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        deltaEditField              matlab.ui.control.EditField
        deltaEditFieldLabel         matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        initialelementButton        matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        addelementButton            matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        RightPanel                  matlab.ui.container.Panel
        WavelengthEditField         matlab.ui.control.EditField
        WavelengthEditFieldLabel    matlab.ui.control.Label
        BeamdiameterEditField       matlab.ui.control.EditField
        BeamdiameterEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        data=table({'Lens'},{''},{''},{''},0,0,0) % Description
        lens=table();
        tempCombox
        tempLabel
        delta
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: addelementButton
        function addelementButtonPushed(app, event)
            app.UITable.Data=app.data;
            app.data=[app.data;table({'Lens'},{''},{''},{''},0,0,0)];
        end

        % Button pushed function: initialelementButton
        function initialelementButtonPushed(app, event)
            app.delta=str2double(app.deltaEditField.Value);
            for ii=1:size(app.data,1)   
                r=str2num(cell2mat(table2array(app.data(ii,2))));
                t=str2num(cell2mat(table2array(app.data(ii,3))));
                d=table2array(app.data(ii,5));
                m=table2array(app.data(ii,4));
                m=textscan(m{1},'%s','Delimiter',' ');
                m=m{1}';
                area=table2array(app.data(ii,7));
                if ~isempty(app.WavelengthEditField.Value)
                    lens_now=Lens(r,t,d,m,str2double(app.WavelengthEditField.Value));
                else
                    lens_now=Lens(r,t,d,m);
                end
                %app.lens(ii,1)=table(lens_now);
                type=table2array(app.data(ii,1));
                if strcmp(type{1},'Lens')
                    Lens.lensThickFcn(lens_now,area,round(area/app.delta));
                elseif strcmp(type{1},'MLA')
                    Lens.lensArrayThickFcn(lens_now,round(d/app.delta),round(area/d));
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            app.data=app.UITable.Data;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
            value = app.WavelengthEditField.Value;
            for ii=1:size(app.lens)
                lens_now=table2array(app.lens(ii,:));
                Lens.indexOfLens(lens_now,str2double(value));
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.tempLabel=uilabel(Parent=app.LeftPanel,Position=[12,214,65,22]);
                app.tempLabel.Text='type';
                app.tempCombox=uidropdown(Parent=app.LeftPanel,Position=[92,214,100,22]);
                app.tempCombox.Items={'Lens','MLA','Diffuser'};
                app.tempCombox.Value=table2cell(app.UITable.Data(indices(1),1));
                set(app.tempCombox, 'ValueChangedFcn', @uidropdown_value_changed_callback);
            else
                app.tempCombox=[];
                app.tempLabel=[];
                %delete(app.tempCombox)
                %delete(app.tempLabel)
            end

            function uidropdown_value_changed_callback(src, event)
                % 获取uitable句柄
                value = src.Value;
                app.UITable.Data(indices(1),indices(2))={value};
                app.data=app.UITable.Data;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {613, '1x', 238};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.addelementButton = uibutton(app.LeftPanel, 'push');
            app.addelementButton.ButtonPushedFcn = createCallbackFcn(app, @addelementButtonPushed, true);
            app.addelementButton.Text = 'add element';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'type'; 'curvature radius'; 'thickness'; 'material'; 'diameter'; 'distance'; 'area'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'system element editor'};
            app.initialelementButton = uibutton(app.LeftPanel, 'push');
            app.initialelementButton.ButtonPushedFcn = createCallbackFcn(app, @initialelementButtonPushed, true);
            app.initialelementButton.Text = 'initial element';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.deltaEditFieldLabel = uilabel(app.LeftPanel);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.LeftPanel, 'text');
            app.deltaEditField.Value = '0.0039';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.BeamdiameterEditFieldLabel = uilabel(app.RightPanel);
            app.BeamdiameterEditFieldLabel.Text = 'Beam diameter';
            app.BeamdiameterEditField = uieditfield(app.RightPanel, 'text');
            app.WavelengthEditFieldLabel = uilabel(app.RightPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.RightPanel, 'text');
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASMDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end