classdef MLAhomogeneizer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ContinuouscalculationCheckBox  matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        BeamparameterTab               matlab.ui.container.Tab
        BeamsizeEditField              matlab.ui.control.EditField
        BeamsizeEditFieldLabel         matlab.ui.control.Label
        NEditField                     matlab.ui.control.EditField
        NEditFieldLabel                matlab.ui.control.Label
        WaveLengthEditField            matlab.ui.control.EditField
        WaveLengthEditFieldLabel       matlab.ui.control.Label
        MLAparameterTab                matlab.ui.container.Tab
        thicknessEditField             matlab.ui.control.EditField
        thicknessEditFieldLabel        matlab.ui.control.Label
        sizeEditField                  matlab.ui.control.EditField
        sizeEditFieldLabel             matlab.ui.control.Label
        rindexEditField                matlab.ui.control.EditField
        rindexEditFieldLabel           matlab.ui.control.Label
        MLAradiusEditField             matlab.ui.control.EditField
        MLAradiusEditFieldLabel        matlab.ui.control.Label
        ExMLATab                       matlab.ui.container.Tab
        sizeEditField_2                matlab.ui.control.EditField
        sizeEditField_2Label           matlab.ui.control.Label
        MLAradiusEditField_2           matlab.ui.control.EditField
        MLAradiusEditField_2Label      matlab.ui.control.Label
        FLparameterTab                 matlab.ui.container.Tab
        thicknessEditField_2           matlab.ui.control.EditField
        thicknessEditField_2Label      matlab.ui.control.Label
        sizeEditField_3                matlab.ui.control.EditField
        sizeEditField_3Label           matlab.ui.control.Label
        rindexEditField_2              matlab.ui.control.EditField
        rindexEditField_2Label         matlab.ui.control.Label
        radiusEditField                matlab.ui.control.EditField
        radiusEditFieldLabel           matlab.ui.control.Label
        DiffuserTab                    matlab.ui.container.Tab
        diffuserangleEditField         matlab.ui.control.EditField
        diffuserangleEditFieldLabel    matlab.ui.control.Label
        SystemparaTab                  matlab.ui.container.Tab
        timesEditField                 matlab.ui.control.EditField
        timesEditFieldLabel            matlab.ui.control.Label
        distance4EditField             matlab.ui.control.EditField
        distance4EditFieldLabel        matlab.ui.control.Label
        distance3EditField             matlab.ui.control.EditField
        distance3EditFieldLabel        matlab.ui.control.Label
        distance2EditField             matlab.ui.control.EditField
        distance2EditFieldLabel        matlab.ui.control.Label
        distance1EditField             matlab.ui.control.EditField
        distance1EditFieldLabel        matlab.ui.control.Label
        AreaEditField                  matlab.ui.control.EditField
        AreaEditFieldLabel             matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        Gauge                          matlab.ui.control.LinearGauge
        GaugeLabel                     matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        lambda=640e-6; % Description
        N=60;
        beamSize=1.6;
        rla
        d
        t
        n
        num
        rla_ex
        d_ex
        rfl
        dfl
        tfl
        nfl
        diffuserAngle
        cohLength
        sigma_f
        sigma_r
        area1
        area2
        Dfl
        distance1,distance2,distance3,distance4
        times
        rate
        peak
        SD % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            addpath('plane wave\')
            beam=GaussianBeam(1,app.beamSize/2,app.lambda);
            beams=GaussianBeam.defocused(beam,10,app.N*app.num);
                
            phase=ones(app.N*app.num,app.N*app.num);
            I=zeros(floor(app.area2/app.rate),floor(app.area2/app.rate));
            %%生成匀光微透镜阵列厚度函数
            [delta_edge_MLA1,delta_mid_MLA1,delta_air_MLA1,t_edge_MLA1]=lensArray_all([app.rla,inf],app.d,app.t,app.N,app.num,'');
            [delta_edge_MLA2,delta_mid_MLA2,delta_air_MLA2,t_edge_MLA2]=lensArray_all([inf,-app.rla],app.d,app.t,app.N,app.num,'');
            
            %% 生成傅里叶透镜厚度函数
            [delta_edge_FL,delta_mid_FL,delta_air_FL,t_edge_FL] = lensPhase_all(app.rfl,app.dfl,app.Dfl,app.tfl,floor(app.area2/app.rate),'','');
            %% 生成激发微透镜阵列厚度函数
            [delta_edge_MLA3,delta_mid_MLA3,delta_air_MLA3,t_edge_MLA3]=lensArray_all([app.rla_ex,inf],app.d_ex,app.t,round(app.d_ex/app.rate),round(app.area2/app.d_ex),'');
            
            delta_edge_MLA3=single(imresize(delta_edge_MLA3,[size(delta_edge_FL,1),size(delta_edge_FL,2)]));
            delta_mid_MLA3=single(imresize(delta_mid_MLA3,[size(delta_mid_FL,1),size(delta_mid_FL,2)]));
            delta_air_MLA3=single(imresize(delta_air_MLA3,[size(delta_air_FL,1),size(delta_air_FL,2)]));
            
            delta_edge_MLA1=single(delta_edge_MLA1);
            delta_edge_MLA2=single(delta_edge_MLA2);
            delta_edge_FL=single(delta_edge_FL);
            delta_mid_MLA1=single(delta_mid_MLA1);
            delta_mid_MLA2=single(delta_mid_MLA2);
            delta_mid_FL=single(delta_mid_FL);
            delta_air_MLA1=single(delta_air_MLA1);
            delta_air_MLA2=single(delta_air_MLA2);
            delta_air_FL=single(delta_air_FL);
            t_edge_MLA1=single(t_edge_MLA1);
            t_edge_MLA2=single(t_edge_MLA2);
            t_edge_FL=single(t_edge_FL);
            beams=single(beams);
            
            if canUseGPU
                delta_edge_MLA1=gpuArray(delta_edge_MLA1);
                delta_edge_MLA2=gpuArray(delta_edge_MLA2);
                delta_edge_MLA3=gpuArray(delta_edge_MLA3);
                delta_edge_FL=gpuArray((delta_edge_FL));
                delta_mid_MLA1=gpuArray((delta_mid_MLA1));
                delta_mid_MLA2=gpuArray((delta_mid_MLA2));
                delta_mid_MLA3=gpuArray((delta_mid_MLA3));
                delta_mid_FL=gpuArray((delta_mid_FL));
                delta_air_MLA1=gpuArray((delta_air_MLA1));
                delta_air_MLA2=gpuArray((delta_air_MLA2));
                delta_air_MLA3=gpuArray((delta_air_MLA3));
                delta_air_FL=gpuArray((delta_air_FL));
                t_edge_MLA1=gpuArray((t_edge_MLA1));
                t_edge_MLA2=gpuArray((t_edge_MLA2));
                t_edge_MLA3=gpuArray((t_edge_MLA3));
                t_edge_FL=gpuArray((t_edge_FL));
                beams=gpuArray((beams));
            end

            tic
            for ii=1:app.times
                %叠加随机相位
                phase=single(diffuser(app.sigma_f,app.sigma_r,app.rate,[app.N*app.num,app.N*app.num]));
                beams=beams.*phase;
                beams=transform(beams,app.area1, app.lambda, 100, [app.rate, app.rate]);
                afterLens=ASMLens(beams,delta_edge_MLA1,delta_mid_MLA1,delta_air_MLA1,t_edge_MLA1,app.lambda,app.n,app.area1);
                F = transform(afterLens, app.area1, app.lambda, app.distance1, [app.rate, app.rate]);
                afterLens = ASMLens(F,delta_edge_MLA2,delta_mid_MLA2,delta_air_MLA2,t_edge_MLA2,app.lambda,app.n,app.area1);
                F = transform(afterLens, app.area2, app.lambda, app.distance2, [app.rate, app.rate]);
                afterLens = ASMLens(F,delta_edge_FL,delta_mid_FL,delta_air_FL,t_edge_FL,app.lambda,app.nfl,app.area2);
                F = transform(afterLens, app.area2, app.lambda, app.distance3, [app.rate, app.rate]);
                afterLens = ASMLens(F,delta_edge_MLA3,delta_mid_MLA3,delta_air_MLA3,t_edge_MLA3,app.lambda,app.n,app.area2);
                F = transform(afterLens, app.area2, app.lambda, app.distance4, [app.rate, app.rate]);
                %F=afterLens;
                I =I+abs(F).^2;

                Inorm=gather((I-min(I,[],'all'))./(max(I,[],'all')-min(I,[],'all')));
                imshow(Inorm,'Parent',app.UIAxes)
                %plot(Inorm(:,3073))
                hist=imhist(Inorm);
                [~,app.peak(end+1)]=max(hist);
                app.SD(end+1)=std(Inorm,0,'all');
                plot(app.peak,'Parent',app.UIAxes2)

                plot(app.SD,'Parent',app.UIAxes3)
                drawnow
                toc
                app.Gauge.Value=ii;
                if app.ContinuouscalculationCheckBox.Value==0
                    pause
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.lambda=str2double(app.WaveLengthEditField.Value);
            app.N=str2double(app.NEditField.Value);
            app.beamSize=str2double(app.BeamsizeEditField.Value);
            %%MLA1,2 parameters
            app.rla=str2double(app.MLAradiusEditField.Value);
            app.d=str2double(app.sizeEditField.Value);
            app.t=str2double(app.thicknessEditField.Value);
            app.n=str2double(app.rindexEditField.Value);
            app.num=floor(app.beamSize/app.d*2+15);
            %%Ex-MLA parameters
            app.rla_ex=str2double(app.MLAradiusEditField_2.Value);
            app.d_ex=str2double(app.sizeEditField_2.Value);
            %%FL lens parameters
            app.rfl=str2num(app.radiusEditField.Value);
            app.dfl = str2double(app.sizeEditField_3.Value);              % 傅里叶透镜直径
            
            app.tfl=str2num(app.thicknessEditField_2.Value);
            app.nfl=str2num(app.rindexEditField_2.Value);
            %%Diffuser parameter
            app.diffuserAngle=str2double(app.diffuserangleEditField.Value);
            app.cohLength=1/(app.diffuserAngle*pi*40);
            app.sigma_f=3*app.cohLength;
            app.sigma_r=sqrt(4 * pi * app.sigma_f^4 / app.cohLength^2);
            %%Beam set
            %%
            app.area1=app.num*app.d;
            app.area2=str2double(app.AreaEditField.Value);
            app.Dfl = app.area2;              %采样区域
            app.distance1=str2double(app.distance1EditField.Value);
            app.distance2=str2double(app.distance2EditField.Value);
            app.distance3=str2double(app.distance3EditField.Value);
            app.distance4=str2double(app.distance4EditField.Value);
            app.times=str2double(app.timesEditField.Value);
            app.rate=app.d/app.N;
            
            app.Gauge.Limits=[0,app.times];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.BeamparameterTab = uitab(app.TabGroup);
            app.BeamparameterTab.Title = 'Beam parameter';
            app.WaveLengthEditFieldLabel = uilabel(app.BeamparameterTab);
            app.WaveLengthEditFieldLabel.Text = 'WaveLength';
            app.WaveLengthEditField = uieditfield(app.BeamparameterTab, 'text');
            app.WaveLengthEditField.Value = '640e-6';
            app.NEditFieldLabel = uilabel(app.BeamparameterTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.BeamparameterTab, 'text');
            app.NEditField.Value = '60';
            app.BeamsizeEditFieldLabel = uilabel(app.BeamparameterTab);
            app.BeamsizeEditFieldLabel.Text = 'Beam size';
            app.BeamsizeEditField = uieditfield(app.BeamparameterTab, 'text');
            app.BeamsizeEditField.Value = '1.6';
            app.MLAparameterTab = uitab(app.TabGroup);
            app.MLAparameterTab.Title = 'MLA parameter';
            app.MLAradiusEditFieldLabel = uilabel(app.MLAparameterTab);
            app.MLAradiusEditFieldLabel.Text = 'MLA radius';
            app.MLAradiusEditField = uieditfield(app.MLAparameterTab, 'text');
            app.MLAradiusEditField.Value = '-0.63';
            app.rindexEditFieldLabel = uilabel(app.MLAparameterTab);
            app.rindexEditFieldLabel.Text = 'r index';
            app.rindexEditField = uieditfield(app.MLAparameterTab, 'text');
            app.rindexEditField.Value = '1.5144';
            app.sizeEditFieldLabel = uilabel(app.MLAparameterTab);
            app.sizeEditFieldLabel.Text = 'size';
            app.sizeEditField = uieditfield(app.MLAparameterTab, 'text');
            app.sizeEditField.Value = '0.145';
            app.thicknessEditFieldLabel = uilabel(app.MLAparameterTab);
            app.thicknessEditFieldLabel.Text = 'thickness';
            app.thicknessEditField = uieditfield(app.MLAparameterTab, 'text');
            app.thicknessEditField.Value = '1';
            app.ExMLATab = uitab(app.TabGroup);
            app.ExMLATab.Title = 'Ex-MLA';
            app.MLAradiusEditField_2Label = uilabel(app.ExMLATab);
            app.MLAradiusEditField_2Label.Text = 'MLA radius';
            app.MLAradiusEditField_2 = uieditfield(app.ExMLATab, 'text');
            app.MLAradiusEditField_2.Value = '-1.944';
            app.sizeEditField_2Label = uilabel(app.ExMLATab);
            app.sizeEditField_2Label.Text = 'size';
            app.sizeEditField_2 = uieditfield(app.ExMLATab, 'text');
            app.sizeEditField_2.Value = '0.18';
            app.FLparameterTab = uitab(app.TabGroup);
            app.FLparameterTab.Title = 'FL parameter';
            app.radiusEditFieldLabel = uilabel(app.FLparameterTab);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.FLparameterTab, 'text');
            app.radiusEditField.Value = ' -557.4000,-137.0900,165.2000';
            app.rindexEditField_2Label = uilabel(app.FLparameterTab);
            app.rindexEditField_2Label.Text = 'r index';
            app.rindexEditField_2 = uieditfield(app.FLparameterTab, 'text');
            app.rindexEditField_2.Value = '1.64416,1.51491';
            app.sizeEditField_3Label = uilabel(app.FLparameterTab);
            app.sizeEditField_3Label.Text = 'size';
            app.sizeEditField_3 = uieditfield(app.FLparameterTab, 'text');
            app.sizeEditField_3.Value = '40';
            app.thicknessEditField_2Label = uilabel(app.FLparameterTab);
            app.thicknessEditField_2Label.Text = 'thickness';
            app.thicknessEditField_2 = uieditfield(app.FLparameterTab, 'text');
            app.thicknessEditField_2.Value = '2,4';
            app.DiffuserTab = uitab(app.TabGroup);
            app.DiffuserTab.Title = 'Diffuser';
            app.diffuserangleEditFieldLabel = uilabel(app.DiffuserTab);
            app.diffuserangleEditFieldLabel.Text = 'diffuser angle';
            app.diffuserangleEditField = uieditfield(app.DiffuserTab, 'text');
            app.diffuserangleEditField.Value = '0.83333';
            app.SystemparaTab = uitab(app.TabGroup);
            app.SystemparaTab.Title = 'System para';
            app.AreaEditFieldLabel = uilabel(app.SystemparaTab);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.SystemparaTab, 'text');
            app.AreaEditField.Value = '20';
            app.distance1EditFieldLabel = uilabel(app.SystemparaTab);
            app.distance1EditFieldLabel.Text = 'distance 1';
            app.distance1EditField = uieditfield(app.SystemparaTab, 'text');
            app.distance1EditField.Value = '0.48';
            app.distance2EditFieldLabel = uilabel(app.SystemparaTab);
            app.distance2EditFieldLabel.Text = 'distance 2';
            app.distance2EditField = uieditfield(app.SystemparaTab, 'text');
            app.distance2EditField.Value = '296.489';
            app.distance3EditFieldLabel = uilabel(app.SystemparaTab);
            app.distance3EditFieldLabel.Text = 'distance 3';
            app.distance3EditField = uieditfield(app.SystemparaTab, 'text');
            app.distance3EditField.Value = '301.583';
            app.distance4EditFieldLabel = uilabel(app.SystemparaTab);
            app.distance4EditFieldLabel.Text = 'distance 4';
            app.distance4EditField = uieditfield(app.SystemparaTab, 'text');
            app.distance4EditField.Value = '3.095';
            app.timesEditFieldLabel = uilabel(app.SystemparaTab);
            app.timesEditFieldLabel.Text = 'times';
            app.timesEditField = uieditfield(app.SystemparaTab, 'text');
            app.timesEditField.Value = '500';
            app.ContinuouscalculationCheckBox = uicheckbox(app.LeftPanel);
            app.ContinuouscalculationCheckBox.Text = 'Continuous calculation';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.GaugeLabel = uilabel(app.RightPanel);
            app.GaugeLabel.Visible = 'off';
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.RightPanel, 'linear');
            app.Gauge.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLAhomogeneizer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end