classdef homogeneizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        BeamTab                        matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        LoadbeamButton                 matlab.ui.control.Button
        GridLayout12                   matlab.ui.container.GridLayout
        NoiselevelSpinner              matlab.ui.control.Spinner
        NoiselevelSpinnerLabel         matlab.ui.control.Label
        WavelengthnmSpinner            matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        BeamwaistsizemmSpinner         matlab.ui.control.Spinner
        BeamwaistsizemmLabel           matlab.ui.control.Label
        MLATab                         matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        SamplepointnumberSpinner       matlab.ui.control.Spinner
        SamplepointnumberSpinnerLabel  matlab.ui.control.Label
        AngleSpinner                   matlab.ui.control.Spinner
        AngleSpinnerLabel              matlab.ui.control.Label
        PitchtolerancemmSpinner        matlab.ui.control.Spinner
        PitchtolerancemmSpinnerLabel   matlab.ui.control.Label
        LoadtoaMLAButton               matlab.ui.control.Button
        TypeButtonGroup                matlab.ui.container.ButtonGroup
        hexagonButton                  matlab.ui.control.ToggleButton
        squareButton                   matlab.ui.control.ToggleButton
        ThicknessmmSpinner             matlab.ui.control.Spinner
        ThicknessmmSpinnerLabel        matlab.ui.control.Label
        numSpinner                     matlab.ui.control.Spinner
        numSpinnerLabel                matlab.ui.control.Label
        MaterialDropDown               matlab.ui.control.DropDown
        MaterialDropDownLabel          matlab.ui.control.Label
        PitchsizemmSpinner             matlab.ui.control.Spinner
        PitchsizemmSpinnerLabel        matlab.ui.control.Label
        RadiusofcurvaturemmSpinner     matlab.ui.control.Spinner
        RadiusofcurvaturemmLabel       matlab.ui.control.Label
        DiffuserTab                    matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        LoaddiffuserButton             matlab.ui.control.Button
        GridLayout15                   matlab.ui.container.GridLayout
        TimeintervalSpinner            matlab.ui.control.Spinner
        TimeintervalSpinnerLabel       matlab.ui.control.Label
        IntegraltimesSpinner           matlab.ui.control.Spinner
        IntegraltimesSpinnerLabel      matlab.ui.control.Label
        MotorspeedRPMSpinner           matlab.ui.control.Spinner
        MotorspeedRPMSpinnerLabel      matlab.ui.control.Label
        RadiusSpinner                  matlab.ui.control.Spinner
        RadiusSpinnerLabel             matlab.ui.control.Label
        DiffuserangleSpinner           matlab.ui.control.Spinner
        DiffuserangleSpinnerLabel      matlab.ui.control.Label
        FLTab                          matlab.ui.container.Tab
        LoadPanel                      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        LoaddatatoalensButton          matlab.ui.control.Button
        LoadalensfromafileButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        SetupTab                       matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        parametersmmPanel              matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        area2Spinner                   matlab.ui.control.Spinner
        area2SpinnerLabel              matlab.ui.control.Label
        areaSpinner                    matlab.ui.control.Spinner
        areaSpinnerLabel               matlab.ui.control.Label
        distance5Spinner               matlab.ui.control.Spinner
        distance5SpinnerLabel          matlab.ui.control.Label
        distance4Spinner               matlab.ui.control.Spinner
        distance4SpinnerLabel          matlab.ui.control.Label
        distance3Spinner               matlab.ui.control.Spinner
        distance3SpinnerLabel          matlab.ui.control.Label
        distance2Spinner               matlab.ui.control.Spinner
        distance2SpinnerLabel          matlab.ui.control.Label
        distance1Spinner               matlab.ui.control.Spinner
        distance1SpinnerLabel          matlab.ui.control.Label
        SimulationTab                  matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        Switch                         matlab.ui.control.ToggleSwitch
        CenterPanel                    matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        AddarowMenu                    matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        openinanotherwindowMenu        matlab.ui.container.Menu
        savedataMenu                   matlab.ui.container.Menu
        ContextMenu3                   matlab.ui.container.ContextMenu
        openinanotherwindowMenu_2      matlab.ui.container.Menu
        savedataMenu_2                 matlab.ui.container.Menu
        ContextMenu4                   matlab.ui.container.ContextMenu
        openinanotherwindowMenu_3      matlab.ui.container.Menu
        savedataMenu_3                 matlab.ui.container.Menu
        ContextMenu5                   matlab.ui.container.ContextMenu
        openinanotherwindowMenu_4      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        data= table(1,0,0,{''},0)% Description
        beam
        diffuser
        MLA1
        MLA2
        FL;
        mymap;
        times
        route
        num
        velocity
        deltat
        pausesignal=0;
        beamChangeFlag=0;
        MLAChangeFlag=0;
        DiffuserChangeFlag=0;
        FLChangeFlag=0;
    end
    
    methods (Access = private)

        function timerCallback(app, event, hEdit)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: AddarowMenu
        function AddarowMenuSelected(app, event)
            app.UITable.Data=app.data;
            app.data=[app.data;table(size(app.data,1)+1,0,0,{''},0)];
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
            
        end

        % Button pushed function: LoadalensfromafileButton
        function LoadalensfromafileButtonPushed(app, event)
            selpath=uigetfile('*.txt');
            app.FL=readLens(selpath,app.WavelengthnmSpinner.Value*10^-6);
            for ii=1:length(app.FL.curRadius)
                if ii<=length(app.FL.curRadius)-1
                    app.data(ii,:)=table(ii,app.FL.curRadius(ii),app.FL.thickness(ii),app.FL.material(ii),app.FL.diameter(ii));
                else
                    app.data(ii,:)=table(ii,app.FL.curRadius(ii),0,{''},app.FL.diameter(ii));
                end
            end
            app.UITable.Data=app.data;
        end

        % Button pushed function: LoaddatatoalensButton
        function LoaddatatoalensButtonPushed(app, event)
            app.data=app.UITable.Data;
            radius=zeros(1,size(app.data,1));
            diameter=zeros(1,size(app.data,1));
            thickness=zeros(1,size(app.data,1)-1);
            material=cell(1,size(app.data,1)-1);
            for ii=1:size(app.data,1)
                radius(ii)=table2array(app.data(ii,'Var2'));
                diameter(ii)=table2array(app.data(ii,'Var5'));
                if ii<=size(app.data,1)-1
                    thickness(ii)=table2array(app.data(ii,'Var3'));
                    material(ii)=table2cell(app.data(ii,'Var4'));   
                end
            end
            app.FL=Lens(radius,thickness,diameter,material,app.WavelengthnmSpinner.Value*10^-6);
        end

        % Button pushed function: LoadtoaMLAButton
        function LoadtoaMLAButtonPushed(app, event)
            radius=[app.RadiusofcurvaturemmSpinner.Value,inf];
            thickness=app.ThicknessmmSpinner.Value;
            pitch=app.PitchsizemmSpinner.Value;
            material={app.MaterialDropDown.Value};
            app.num=app.numSpinner.Value;
            app.num=floor(app.num*app.BeamwaistsizemmSpinner.Value/2.85/pitch);
            app.MLA1=Lens(radius,thickness,pitch,material,app.WavelengthnmSpinner.Value*10^-6);
            app.MLA2=Lens(flip(-radius),thickness,pitch,material,app.WavelengthnmSpinner.Value*10^-6);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.pausesignal=0;
            app.StartButton.Enable='off';
            app.StopButton.Enable='on';
            % 创建 timer 对象的代码
            timePeriod = 1.0;
            t = timer('TimerFcn',@app.timerCallback,'Period', timePeriod);
            
            % 启动定时器
            start(t);

            N=app.SamplepointnumberSpinner.Value;
            lambda=app.WavelengthnmSpinner.Value*10^-6;
            
            if isempty(app.num)
                LoadtoaMLAButtonPushed(app, event)
            end
            area1=app.num*app.MLA1.diameter;
            area2=app.areaSpinner.Value;
            area3=app.area2Spinner.Value;
            distance1=app.distance1Spinner.Value;
            distance2=app.distance2Spinner.Value;
            distance3=app.distance3Spinner.Value;
            distance4=app.distance4Spinner.Value;
            distance5=app.distance5Spinner.Value;
            
            rate=app.MLA1.diameter(1)/N;
            if isempty(app.beam)||app.beamChangeFlag==1
                LoadbeamButtonPushed(app, event)
            end
            beams=GaussianBeam.defocused(app.beam,10,N*app.num,rate);%.*drawCircle(round(N*app.num)/2,0,0,round(area1/rate),round(area1/rate));
            if isempty(app.diffuser)
                LoaddiffuserButtonPushed(app, event)
            end
            phase=Diffuser.generateDiffuser(app.diffuser,rate,[round(app.route/rate)+size(beams,1),N*app.num]);
            type=app.TypeButtonGroup.SelectedObject.Text;
            %%生成匀光微透镜阵列厚度函数
            valid_l=app.MLA1.diameter(1)-app.PitchtolerancemmSpinner.Value;
            Lens.lensArrayThickFcn(app.MLA1,N,app.num,type,app.AngleSpinner.Value,valid_l);
            Lens.lensArrayThickFcn(app.MLA2,N,app.num,type,app.AngleSpinner.Value,valid_l);
            %% 生成傅里叶透镜厚度函数
            if isempty(app.FL)
                %LoadalensfromafileButtonPushed(app, event)
                selpath='E:\Project File\SIM\matlab程序\衍射\镜头\OLD2440-T2M(JCOPTIX-F=74.9胶合).txt';
                app.FL=readLens(selpath,app.WavelengthnmSpinner.Value*10^-6);
                for ii=1:length(app.FL.curRadius)
                    if ii<=length(app.FL.curRadius)-1
                        app.data(ii,:)=table(ii,app.FL.curRadius(ii),app.FL.thickness(ii),app.FL.material(ii),app.FL.diameter(ii));
                    else
                        app.data(ii,:)=table(ii,app.FL.curRadius(ii),0,{''},app.FL.diameter(ii));
                    end
                end
                app.UITable.Data=app.data;
            end
            if app.FLChangeFlag==1
                LoaddatatoalensButtonPushed(app, event);
            end
            Lens.lensThickFcn(app.FL,area2,round(area2/rate));

            Lens.lensSingle(app.MLA1)
            Lens.lensSingle(app.MLA2)
            Lens.lensSingle(app.FL);

            beams=single(beams);
            phase=single(phase);

            if app.Switch.Value=='GPU'
                Lens.lensGPUArray(app.MLA1)
                Lens.lensGPUArray(app.MLA2)
                Lens.lensGPUArray(app.FL)
                beams=gpuArray(beams);
                phase=gpuArray(phase);
            end
            xaxis=linspace(-area3/2,area3/2,area3/rate);
            taxis=0;
            u=[];
            sd=[];
            %%主循环
            for ii=1:app.times

                if app.pausesignal==1
                    warndlg('Simulation paused','Tip');
                    break
                end
                %叠加随机相位
                beamss=GaussianBeam.beamNoise(beams,0,app.NoiselevelSpinner.Value);
                F = transform(beamss, area1, lambda, distance1, [rate, rate]);
                progressBar([10,100],0.1,app.UIAxes4_2)
                drawnow
                index=round((app.velocity*(ii-1)*app.deltat)/rate+1);
                F=F.*phase(index:index+size(F,1)-1,:);
                F = transform(F, area1, lambda, distance2, [rate, rate]);
                progressBar([10,100],0.2,app.UIAxes4_2)
                drawnow
                afterLens=ASMinLens(F,app.MLA1,lambda,area1);
                progressBar([10,100],0.3,app.UIAxes4_2)
                drawnow
                F = transform(afterLens, area1, lambda, distance3, [rate, rate]);
                progressBar([10,100],0.4,app.UIAxes4_2)
                drawnow
                afterLens=ASMinLens(F,app.MLA2,lambda,area1);
                progressBar([10,100],0.5,app.UIAxes4_2)
                drawnow
                F = transform(afterLens, area2, lambda, distance4, [rate, rate]);
                progressBar([10,100],0.7,app.UIAxes4_2)
                drawnow
                afterLens = ASMinLens(F,app.FL,lambda,area2);
                progressBar([10,100],0.9,app.UIAxes4_2)
                drawnow
                F = transform(afterLens, area3, lambda, distance5, [rate, rate]);
                progressBar([10,100],1,app.UIAxes4_2)
                drawnow
                I_now=abs(F).^2;
                if ii==1
                    I=zeros(size(F));
                end
                I = I+I_now;
                
                Inorm=imnorm(I);
                imshow(uint16(Inorm*65536),app.mymap,'Parent',app.UIAxes)
                yticks(app.UIAxes3,0:0.2:1)
                if length(xaxis)~=size(Inorm,1)
                    xaxis(end+1)=xaxis(end)+rate;
                end
                plot(xaxis,Inorm(round(size(Inorm,1)/2),:),'Parent',app.UIAxes5)
                [u(end+1),sd(end+1)]=homoanalyse(Inorm);
                taxis(ii)=ii*app.deltat;
                yticks(app.UIAxes2,0:0.2:1)
                plot(taxis,u,'Parent',app.UIAxes2)
                yticks(app.UIAxes3,0:20:100)
                plot(taxis,sd,'Parent',app.UIAxes3)
                progressBar([100,1000],ii/app.times,app.UIAxes4)
                drawnow
                taxis=[taxis,0];
            end
        end

        % Button pushed function: LoadbeamButton
        function LoadbeamButtonPushed(app, event)
            beamAmp=1;
            beamStd=app.BeamwaistsizemmSpinner.Value/2.85;
            app.beam=GaussianBeam(beamAmp,beamStd,app.WavelengthnmSpinner.Value);
            color=wavelength2color(app.WavelengthnmSpinner.Value, 'gammaVal', 2.2, 'maxIntensity', 1, 'colorSpace', 'rgb');
            R=linspace(0,color(1),65535)';
            G=linspace(0,color(2),65535)';
            B=linspace(0,color(3),65535)';
            app.mymap=[R G B];
        end

        % Button pushed function: LoaddiffuserButton
        function LoaddiffuserButtonPushed(app, event)
            N=app.SamplepointnumberSpinner.Value;
            rate=app.MLA1.diameter(1)/N;
            rpus=app.MotorspeedRPMSpinner.Value/60/1000/1000;
            radius_diffuser=app.RadiusSpinner.Value;%散射片半径mm
            app.velocity=2*pi*rpus*radius_diffuser;%线速度
            app.deltat=app.TimeintervalSpinner.Value*rate/app.velocity;%
            T=app.IntegraltimesSpinner.Value;%积分时间（μs）
            app.times=floor(T/app.deltat);
            app.route=app.velocity*T;
            
            if app.route>2*pi*radius_diffuser
                app.route=2*pi*radius_diffuser;
                T=app.route/app.velocity;
                app.times=floor(T/app.deltat);
            end
            diffuserAngle=app.DiffuserangleSpinner.Value;   %散射片散射角度
            app.diffuser=Diffuser(diffuserAngle,app.WavelengthnmSpinner.Value*10^-6);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.pausesignal=1;
            app.StartButton.Enable='on';
            app.StopButton.Enable='off';
        end

        % Menu selected function: openinanotherwindowMenu
        function openinanotherwindowMenuSelected(app, event)
             h =findall(app.UIAxes5, 'type', 'line');
             x = get(h, 'Xdata');
             y = get(h, 'Ydata');
             figure
             plot(x,y)
        end

        % Menu selected function: openinanotherwindowMenu_3
        function openinanotherwindowMenu_3Selected(app, event)
             h =findall(app.UIAxes3, 'type', 'line');
             x = get(h, 'Xdata');
             y = get(h, 'Ydata');
             figure
             plot(x,y)
        end

        % Menu selected function: openinanotherwindowMenu_2
        function openinanotherwindowMenu_2Selected(app, event)
             h =findall(app.UIAxes2, 'type', 'line');
             x = get(h, 'Xdata');
             y = get(h, 'Ydata');
             figure
             plot(x,y)
        end

        % Menu selected function: savedataMenu
        function savedataMenuSelected(app, event)
             h =findall(app.UIAxes5, 'type', 'line');
             x = get(h, 'Xdata');
             y = get(h, 'Ydata');
             selpath=uigetdir;
             save([selpath '\2dprofile.mat'],"x","y")
        end

        % Menu selected function: savedataMenu_2
        function savedataMenu_2Selected(app, event)
             h =findall(app.UIAxes2, 'type', 'line');
             x = get(h, 'Xdata');
             y = get(h, 'Ydata');
             selpath=uigetdir;
             save([selpath '\uniformity.mat'],"x","y")
        end

        % Menu selected function: savedataMenu_3
        function savedataMenu_3Selected(app, event)
             h =findall(app.UIAxes3, 'type', 'line');
             x = get(h, 'Xdata');
             y = get(h, 'Ydata');
             selpath=uigetdir;
             save([selpath '\sd.mat'],"x","y")
        end

        % Value changed function: BeamwaistsizemmSpinner, 
        % ...and 2 other components
        function WavelengthnmSpinnerValueChanged(app, event)
            app.beamChangeFlag=1;
        end

        % Callback function: AngleSpinner, MaterialDropDown, 
        % ...and 7 other components
        function RadiusofcurvaturemmSpinnerValueChanged(app, event)
            app.MLAChangeFlag=1;
        end

        % Value changed function: DiffuserangleSpinner, 
        % ...and 4 other components
        function DiffuserangleSpinnerValueChanged(app, event)
            app.DiffuserChangeFlag=1;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.FLChangeFlag=1;
        end

        % Menu selected function: openinanotherwindowMenu_4
        function openinanotherwindowMenu_4Selected(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {386, '1x', 307};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.BeamTab = uitab(app.TabGroup);
            app.BeamTab.Title = 'Beam';
            app.GridLayout11 = uigridlayout(app.BeamTab);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.BeamwaistsizemmLabel = uilabel(app.GridLayout12);
            app.BeamwaistsizemmLabel.Text = 'Beam waist size(mm)';
            app.BeamwaistsizemmSpinner = uispinner(app.GridLayout12);
            app.BeamwaistsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmSpinnerValueChanged, true);
            app.BeamwaistsizemmSpinner.Value = 2;
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = 'Wavelength     (nm)';
            app.WavelengthnmSpinner = uispinner(app.GridLayout12);
            app.WavelengthnmSpinner.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmSpinnerValueChanged, true);
            app.WavelengthnmSpinner.Value = 647;
            app.NoiselevelSpinnerLabel = uilabel(app.GridLayout12);
            app.NoiselevelSpinnerLabel.Text = 'Noise level';
            app.NoiselevelSpinner = uispinner(app.GridLayout12);
            app.NoiselevelSpinner.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmSpinnerValueChanged, true);
            app.NoiselevelSpinner.Value = 0.5;
            app.LoadbeamButton = uibutton(app.GridLayout11, 'push');
            app.LoadbeamButton.ButtonPushedFcn = createCallbackFcn(app, @LoadbeamButtonPushed, true);
            app.LoadbeamButton.Text = 'Load beam';
            app.MLATab = uitab(app.TabGroup);
            app.MLATab.Title = 'MLA';
            app.GridLayout3 = uigridlayout(app.MLATab);
            app.RadiusofcurvaturemmLabel = uilabel(app.GridLayout3);
            app.RadiusofcurvaturemmLabel.Text = 'Radius of curvature(mm)';
            app.RadiusofcurvaturemmSpinner = uispinner(app.GridLayout3);
            app.RadiusofcurvaturemmSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.RadiusofcurvaturemmSpinner.Value = -0.94;
            app.PitchsizemmSpinnerLabel = uilabel(app.GridLayout3);
            app.PitchsizemmSpinnerLabel.Text = 'Pitch size    (mm)';
            app.PitchsizemmSpinner = uispinner(app.GridLayout3);
            app.PitchsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.PitchsizemmSpinner.Value = 0.3;
            app.MaterialDropDownLabel = uilabel(app.GridLayout3);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.GridLayout3);
            app.MaterialDropDown.Items = {'F_SILICA', 'S-TIH53', 'D-K9'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.MaterialDropDown.Value = 'F_SILICA';
            app.numSpinnerLabel = uilabel(app.GridLayout3);
            app.numSpinnerLabel.Text = 'num';
            app.numSpinner = uispinner(app.GridLayout3);
            app.numSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.numSpinner.Value = 6;
            app.ThicknessmmSpinnerLabel = uilabel(app.GridLayout3);
            app.ThicknessmmSpinnerLabel.Text = 'Thickness    (mm)';
            app.ThicknessmmSpinner = uispinner(app.GridLayout3);
            app.ThicknessmmSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.ThicknessmmSpinner.Value = 1;
            app.TypeButtonGroup = uibuttongroup(app.GridLayout3);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.squareButton = uitogglebutton(app.TypeButtonGroup);
            app.squareButton.Text = 'square';
            app.squareButton.Value = true;
            app.hexagonButton = uitogglebutton(app.TypeButtonGroup);
            app.hexagonButton.Text = 'hexagon';
            app.LoadtoaMLAButton = uibutton(app.GridLayout3, 'push');
            app.LoadtoaMLAButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtoaMLAButtonPushed, true);
            app.LoadtoaMLAButton.Text = 'Load to a MLA';
            app.PitchtolerancemmSpinnerLabel = uilabel(app.GridLayout3);
            app.PitchtolerancemmSpinnerLabel.Text = 'Pitch tolerance    (mm)';
            app.PitchtolerancemmSpinner = uispinner(app.GridLayout3);
            app.PitchtolerancemmSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.PitchtolerancemmSpinner.Value = 0.02;
            app.AngleSpinnerLabel = uilabel(app.GridLayout3);
            app.AngleSpinnerLabel.Text = 'Angle   (°)';
            app.AngleSpinner = uispinner(app.GridLayout3);
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.SamplepointnumberSpinnerLabel = uilabel(app.GridLayout3);
            app.SamplepointnumberSpinnerLabel.Text = 'Sample point number';
            app.SamplepointnumberSpinner = uispinner(app.GridLayout3);
            app.SamplepointnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofcurvaturemmSpinnerValueChanged, true);
            app.SamplepointnumberSpinner.Value = 80;
            app.DiffuserTab = uitab(app.TabGroup);
            app.DiffuserTab.Title = 'Diffuser';
            app.GridLayout14 = uigridlayout(app.DiffuserTab);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.DiffuserangleSpinnerLabel = uilabel(app.GridLayout15);
            app.DiffuserangleSpinnerLabel.Text = 'Diffuser angle';
            app.DiffuserangleSpinner = uispinner(app.GridLayout15);
            app.DiffuserangleSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffuserangleSpinnerValueChanged, true);
            app.DiffuserangleSpinner.Value = 3;
            app.RadiusSpinnerLabel = uilabel(app.GridLayout15);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.GridLayout15);
            app.RadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffuserangleSpinnerValueChanged, true);
            app.RadiusSpinner.Value = 22;
            app.MotorspeedRPMSpinnerLabel = uilabel(app.GridLayout15);
            app.MotorspeedRPMSpinnerLabel.Text = 'Motor speed(RPM)';
            app.MotorspeedRPMSpinner = uispinner(app.GridLayout15);
            app.MotorspeedRPMSpinner.Step = 100;
            app.MotorspeedRPMSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffuserangleSpinnerValueChanged, true);
            app.MotorspeedRPMSpinner.Value = 50000;
            app.IntegraltimesSpinnerLabel = uilabel(app.GridLayout15);
            app.IntegraltimesSpinnerLabel.Text = 'Integral time(μs)';
            app.IntegraltimesSpinner = uispinner(app.GridLayout15);
            app.IntegraltimesSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffuserangleSpinnerValueChanged, true);
            app.IntegraltimesSpinner.Value = 1000;
            app.TimeintervalSpinnerLabel = uilabel(app.GridLayout15);
            app.TimeintervalSpinnerLabel.Text = 'Time interval';
            app.TimeintervalSpinner = uispinner(app.GridLayout15);
            app.TimeintervalSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffuserangleSpinnerValueChanged, true);
            app.TimeintervalSpinner.Value = 50;
            app.LoaddiffuserButton = uibutton(app.GridLayout14, 'push');
            app.LoaddiffuserButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddiffuserButtonPushed, true);
            app.LoaddiffuserButton.Text = 'Load diffuser';
            app.FLTab = uitab(app.TabGroup);
            app.FLTab.Title = 'FL';
            app.UITable = uitable(app.FLTab);
            app.UITable.ColumnName = {'Surface'; 'Radius of cuvature'; 'Thickness'; 'Material'; 'Diameter'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LoadPanel = uipanel(app.FLTab);
            app.LoadPanel.Title = 'Load';
            app.GridLayout5 = uigridlayout(app.LoadPanel);
            app.LoadalensfromafileButton = uibutton(app.GridLayout5, 'push');
            app.LoadalensfromafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadalensfromafileButtonPushed, true);
            app.LoadalensfromafileButton.Text = 'Load a lens from a file';
            app.LoaddatatoalensButton = uibutton(app.GridLayout5, 'push');
            app.LoaddatatoalensButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatatoalensButtonPushed, true);
            app.LoaddatatoalensButton.Text = 'Load data to a lens';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.parametersmmPanel = uipanel(app.SetupTab);
            app.parametersmmPanel.Title = 'parameters(mm)';
            app.GridLayout6 = uigridlayout(app.parametersmmPanel);
            app.distance1SpinnerLabel = uilabel(app.GridLayout6);
            app.distance1SpinnerLabel.Text = 'distance1';
            app.distance1Spinner = uispinner(app.GridLayout6);
            app.distance1Spinner.Value = 20;
            app.distance2SpinnerLabel = uilabel(app.GridLayout6);
            app.distance2SpinnerLabel.Text = 'distance2';
            app.distance2Spinner = uispinner(app.GridLayout6);
            app.distance2Spinner.Value = 20;
            app.distance3SpinnerLabel = uilabel(app.GridLayout6);
            app.distance3SpinnerLabel.Text = 'distance3';
            app.distance3Spinner = uispinner(app.GridLayout6);
            app.distance3Spinner.Value = 1.5;
            app.distance4SpinnerLabel = uilabel(app.GridLayout6);
            app.distance4SpinnerLabel.Text = 'distance4';
            app.distance4Spinner = uispinner(app.GridLayout6);
            app.distance4Spinner.Value = 70;
            app.distance5SpinnerLabel = uilabel(app.GridLayout6);
            app.distance5SpinnerLabel.Text = 'distance5';
            app.distance5Spinner = uispinner(app.GridLayout6);
            app.distance5Spinner.Value = 75;
            app.areaSpinnerLabel = uilabel(app.GridLayout6);
            app.areaSpinnerLabel.Text = 'area';
            app.areaSpinner = uispinner(app.GridLayout6);
            app.areaSpinner.Value = 18;
            app.area2SpinnerLabel = uilabel(app.GridLayout6);
            app.area2SpinnerLabel.Text = 'area2';
            app.area2Spinner = uispinner(app.GridLayout6);
            app.area2Spinner.Value = 12.8;
            app.Image = uiimage(app.SetupTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '系统图3.svg');
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.GridLayout7 = uigridlayout(app.SimulationTab);
            app.Switch = uiswitch(app.GridLayout7, 'toggle');
            app.Switch.Items = {'CPU', 'GPU'};
            app.Switch.Value = 'GPU';
            app.GridLayout10 = uigridlayout(app.GridLayout7);
            app.StartButton = uibutton(app.GridLayout10, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout10, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.CenterPanel);
            app.UIAxes4 = uiaxes(app.GridLayout8);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes = uiaxes(app.GridLayout8);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes4_2 = uiaxes(app.GridLayout8);
            app.UIAxes4_2.XTick = [];
            app.UIAxes4_2.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes2, 'integral time(μs)')
            ylabel(app.UIAxes2, 'uniformity')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes3, 'integral time(μs)')
            ylabel(app.UIAxes3, 'standard deviation')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, '2D profile')
            xlabel(app.UIAxes5, 'X(mm)')
            ylabel(app.UIAxes5, 'intensity')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.AddarowMenu = uimenu(app.ContextMenu);
            app.AddarowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddarowMenuSelected, true);
            app.AddarowMenu.Text = 'Add a row';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.openinanotherwindowMenu = uimenu(app.ContextMenu2);
            app.openinanotherwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @openinanotherwindowMenuSelected, true);
            app.openinanotherwindowMenu.Text = 'open in another window';
            app.savedataMenu = uimenu(app.ContextMenu2);
            app.savedataMenu.MenuSelectedFcn = createCallbackFcn(app, @savedataMenuSelected, true);
            app.savedataMenu.Text = 'save data';
            % Assign app.ContextMenu2
            app.UIAxes5.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.openinanotherwindowMenu_2 = uimenu(app.ContextMenu3);
            app.openinanotherwindowMenu_2.MenuSelectedFcn = createCallbackFcn(app, @openinanotherwindowMenu_2Selected, true);
            app.openinanotherwindowMenu_2.Text = 'open in another window';
            app.savedataMenu_2 = uimenu(app.ContextMenu3);
            app.savedataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @savedataMenu_2Selected, true);
            app.savedataMenu_2.Text = 'save data';
            % Assign app.ContextMenu3
            app.UIAxes2.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.openinanotherwindowMenu_3 = uimenu(app.ContextMenu4);
            app.openinanotherwindowMenu_3.MenuSelectedFcn = createCallbackFcn(app, @openinanotherwindowMenu_3Selected, true);
            app.openinanotherwindowMenu_3.Text = 'open in another window';
            app.savedataMenu_3 = uimenu(app.ContextMenu4);
            app.savedataMenu_3.MenuSelectedFcn = createCallbackFcn(app, @savedataMenu_3Selected, true);
            app.savedataMenu_3.Text = 'save data';
            % Assign app.ContextMenu4
            app.UIAxes3.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.UIFigure);
            app.openinanotherwindowMenu_4 = uimenu(app.ContextMenu5);
            app.openinanotherwindowMenu_4.MenuSelectedFcn = createCallbackFcn(app, @openinanotherwindowMenu_4Selected, true);
            app.openinanotherwindowMenu_4.Text = 'open in another window';
            % Assign app.ContextMenu5
            app.UIAxes.ContextMenu = app.ContextMenu5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = homogeneizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end